classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TopoplotPanel  matlab.ui.container.Panel
        SelectButton   matlab.ui.control.Button
        SaveButton     matlab.ui.control.Button
        FischerAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        FischerScores  % Matrice dei punteggi di Fischer
        RowLabels      % Etichette dei canali
        ColLabels      % Etichette delle bande di frequenza
        Bands          % Bande di frequenza analizzate
        c_subject         % Soggetto registrato
        SelectedFeatures  % Feature selezionate dall'utente
        SelectedCells  % Celle selezioante
        SelectedCh     % Canali selezionati per la visualizzazione
        LogbandPower   % Logarithmic band power
        ElectrodePos   % Chanlocs
    end

    
    methods (Access = private)

        function loadData(app)
            global TestType;
            % load the current subject
            subj = load('c_subject.mat');
            app.c_subject = subj.c_subject;

            %baseDir = fullfile('C:','Users','User','Desktop','MATLAB','CVSA', 'records', app.c_subject, 'dataset');
             baseDir = fullfile('home', 'riccardo', 'test_ws', 'records', app.c_subject, 'dataset');
            % Determina i file da caricare in base al tipo di test
            if strcmp(TestType, 'evaluation')
                f_path = fullfile(baseDir, 'fischer_scores_ev.mat');
                l_path = fullfile(baseDir, 'logband_power_ev.mat');
            else
                f_path = fullfile(baseDir, 'fischer_scores.mat');
                l_path = fullfile(baseDir, 'logband_power.mat');
            end   
            % load fischer scores, logband data and electrodes position
            data = load(f_path);
            app.FischerScores = data.cva_selected;
            app.RowLabels = data.rowLabels;
            app.ColLabels = data.colLabels;
            app.Bands = data.band;
            
            logbandData = load(l_path);
            app.LogbandPower = logbandData.logbandPower;
            app.ElectrodePos = logbandData.electrodePos;
        end

        % Configura il grafico dei punteggi di Fischer
        function configureFischerAxes(app)
            % Visualizza la matrice dei punteggi di Fischer come immagine a colori

            imagesc(app.FischerAxes, app.FischerScores);
            colormap(app.FischerAxes, 'jet');
            colorbar(app.FischerAxes);
            app.FischerAxes.XTick = 1:length(app.ColLabels);
            app.FischerAxes.XTickLabel = app.ColLabels;
            app.FischerAxes.YTick = 1:length(app.RowLabels);
            app.FischerAxes.YTickLabel = app.RowLabels;
            app.FischerAxes.XLabel.String = 'Bande di Frequenza';
            app.FischerAxes.YLabel.String = 'Canali';

            % Abilita il datacursor mode
            dcm = datacursormode(app.UIFigure);
            dcm.Enable = 'on';
            dcm.DisplayStyle = 'datatip';
            dcm.UpdateFcn = @(src, event) app.ImageClickCallback(src, event);
        end

         % Configura i subplot dei topoplot per ogni banda di frequenza
        function configureTopoplotSubplots(app)
            numBands = size(app.LogbandPower, 2);
            numRows = ceil(sqrt(numBands)); % Calcola il numero di righe per il layout dei subplot
            numCols = ceil(numBands/numRows); % Calcola il numero di colonne per il layout dei subplot
            % Dimensioni e margini per i subplot
            axWidth = 1/numCols;
            axHeight = 1/numRows;

             for i = 1:numBands
                row = floor((i-1)/numCols);
                col = mod(i-1, numCols);

                % Calcola la posizione di ciascun asse
                axPos = [col * axWidth, (numRows - row - 1) * axHeight, axWidth, axHeight];
                bandData = squeeze(app.LogbandPower(:,i));
                limits = [-max(abs(bandData)) max(abs(bandData))];
                
                % Crea una figura temporanea per il topoplot
                tempFig = figure('Visible', 'off');
                tempAxes = axes(tempFig);
                topoplot(bandData, app.ElectrodePos, 'style', 'both', 'electrodes', 'labelpoint','headrad','rim','maplimits',limits);
                cbar = colorbar(tempAxes);
                cbar.Limits = limits;

                frame = getframe(tempFig);
                img = frame2im(frame);
                close(tempFig);

                % Crea gli assi e visualizza l'immagine del topoplot
                ax = axes('Parent', app.TopoplotPanel, 'Position', axPos);
                imshow(img, 'Parent', ax);
                colormap jet
                title(ax,app.ColLabels{i});
             end
        end


        % Callback per selezione delle celle nell'immagine
        function txt = ImageClickCallback(app,~,event)
            % Ottieni le coordinate del click del mouse
            pos = event.Position;
            x = pos(1);
            y = pos(2);

            % Verifica se le coordinate sono valide
            if x >= 1 && x <= size(app.FischerScores, 2) && y >= 1 && y <= size(app.FischerScores, 1)
                 % Controlla se la cella è già stata selezionata
                if ~isempty(app.SelectedCells) && any(ismember(app.SelectedCells, [y, x], 'rows'))
                    % Rimuovi la selezione cliccando nuovamente sulla cella
                    app.SelectedCells = app.SelectedCells(~ismember(app.SelectedCells, [y, x], 'rows'), :);
                    % Rimuovi il marcatore rosso
                    plot(app.FischerAxes, x, y, 'w.'); % Usa 'w.' per un punto bianco
                else
                    % Aggiungi le coordinate selezionate
                    if isempty(app.SelectedCells)
                        app.SelectedCells = [y, x];
                    else
                        app.SelectedCells = [app.SelectedCells; y, x];
                    end

                    % Aggiorna l'immagine per mostrare le celle selezionate
                    hold(app.FischerAxes, 'on');
                    plot(app.FischerAxes, x, y, 'ro', 'MarkerSize', 10);
                    hold(app.FischerAxes, 'off');
                end
                
                % Testo visualizzato nel datatip
                txt = {['Band: ', app.ColLabels{x}], ...
                       ['Channel: ', app.RowLabels{y}], ...
                       ['Fischer Score: ', num2str(app.FischerScores(y, x))]};
            else
                txt = '';
            end 
        end
           
        
        function loadSelectedFeatures(app)
            global TestType;
            % Percorso di caricamento
            s_path = fullfile('/home/riccardo/test_ws/records', app.c_subject, 'dataset');
            % Carica le feature selezionate dalla calibration
            if strcmp(TestType, 'evaluation')
                filename = 'selected_features.mat';
                if exist(fullfile(s_path, filename), 'file')
                    data = load(fullfile(s_path, filename));
                    app.SelectedFeatures = data.selectedFeatures;
                    disp('Loaded selected features from calibration.');
                else
                    uialert(app.UIFigure, 'File di feature selezionate non trovato.', 'Errore');
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initializeApp(app)
            % Carica i dati
            app.loadData();
            % Configura il grafico dei punteggi di Fischer
            app.configureFischerAxes();
             % Configura i subplot dei topoplot
            app.configureTopoplotSubplots();

            global TestType;
            if strcmp(TestType, 'evaluation')
                app.loadSelectedFeatures();
            end
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
             % Ottieni le celle selezionate nella UIAxes
            selectedCells = app.SelectedCells;

            if isempty(selectedCells)
                uialert(app.UIFigure, 'Nessuna cella selezionata', 'Attenzione');
                return;
            end

            % Estrai i nomi delle feature selezionate
            app.SelectedFeatures = cell(size(selectedCells, 1), 3);
            for i = 1:size(selectedCells, 1)
                row = selectedCells(i, 1);
                col = selectedCells(i, 2);
                app.SelectedFeatures{i, 1} = app.RowLabels{row};
                app.SelectedFeatures{i, 2} = app.Bands{col};
                app.SelectedFeatures{i, 3} = app.FischerScores(row, col);
            end

            % Mostra le feature selezionate nella console MATLAB
            disp('Selected features:');
            disp(app.SelectedFeatures);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            global TestType 
            if isempty(app.SelectedFeatures)
                % Avvisa l'utente se non ha selezionato nessuna feature
                uialert(app.UIFigure, 'Nessuna feature selezionata', 'Attenzione');
            else
                % Salva le feature selezionate in un file .mat
                %s_path = ['C:\Users\User\Desktop\MATLAB\CVSA\records\' app.c_subject '\dataset'];
                 s_path = fullfile('/home/riccardo/test_ws/records', app.c_subject, 'dataset');
                % Determina il nome del file di salvataggio in base al tipo di test
                if strcmp(TestType, 'calibration')
                    filename = 'selected_features.mat';
                else
                    filename = 'selected_features_temp.mat'; % Può essere un nome temporaneo
                end
        
                % Salva le feature selezionate
                selectedFeatures = app.SelectedFeatures; 
                save(fullfile(s_path, filename), 'selectedFeatures', '-mat');
                uialert(app.UIFigure, 'Features salvate con successo', 'Successo');
        
                % Verifica che i file siano salvati
                timeout = 10; % Secondi
                startTime = tic;
                while toc(startTime) < timeout
                    if exist(fullfile(s_path, filename), 'file')
                        disp('File found. Starting dataset creation');
                        % Chiama la funzione createDataset con l'input appropriato
                        createDataset(app.c_subject, TestType); 
                        break;
                    else
                        pause(1); % Attendi 1 secondo prima di controllare di nuovo
                    end
                end           
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FischerAxes = uiaxes(app.UIFigure);
            title(app.FischerAxes, 'Fischer Score')
            xlabel(app.FischerAxes, 'Frequency Bands [Hz]')
            ylabel(app.FischerAxes, 'Channels')
            colormap(app.FischerAxes, 'jet')
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.TopoplotPanel = uipanel(app.UIFigure);
            app.TopoplotPanel.Title = 'TopoplotPanel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initializeApp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end