classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        SaveButton    matlab.ui.control.Button
        SelectButton  matlab.ui.control.Button
        FischerTable  matlab.ui.control.Table
    end

    
    properties (Access = private)
        FischerScores  % Matrice dei punteggi di Fischer
        RowLabels      % Etichette dei canali
        ColLabels      % Etichette delle bande di frequenza
        SelectedFeatures  % Feature selezionate dall'utente
        SelectedCells  % Celle selezionate
        SelectedCh     % Indici canali selezionati per la visualizzazione
    end
    
    methods (Access = private)
        
         % Carica i dati dei punteggi di Fischer da un file .mat
        function loadData(app)
            data = load('fischer_scores.mat');
            app.FischerScores = data.cva_total;
            app.RowLabels = data.rowLabels;
            app.ColLabels = data.colLabels;
            app.SelectedCh = data.selectedch;
        end

        % Configura la tabella dei punteggi di Fischer
        function configureFischerTable(app)
            % Crea la tabella con le etichette e i punteggi di Fischer
            app.FischerTable.Data = num2cell(app.FischerScores);
            app.FischerTable.RowName = app.RowLabels;
            app.FischerTable.ColumnName = app.ColLabels;

            % Applica il color coding basato sui valori dei punteggi di Fischer
            app.applyColorCoding();
        end

        % Applica il color coding alla tabella
        function applyColorCoding(app)
            % Ottieni i dati dalla tabella
            data = app.FischerTable.Data;
            % Ottieni i valori assoluti dei punteggi di Fischer
            absScores = cellfun(@abs, data);

            % Definisci una mappa di colori
            colormap = jet; % Usa la mappa di colori 'jet'
            nColors = size(colormap, 1);
            
            % Normalizza i punteggi da 1 a nColors
            normScores = ceil(nColors * mat2gray(absScores));

            % Applica il color coding a ogni cella
            for row = 1:size(data, 1)
                for col = 1:size(data, 2)
                    color = colormap(normScores(row,col), :);
                    set(app.FischerTable, 'BackgroundColor', row, col, color);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initializeApp(app)
            % Carica i dati
            app.loadData();
            % Configura la tabella dei punteggi di Fischer
            app.configureFischerTable(); 
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            % Ottieni le celle selezionate nella tabella
            selectedCells = app.FischerTable.Selection;

            if isempty(selectedCells)
                uialert(app.UIFigure, 'Nessuna cella selezionata', 'Attenzione');
                return;
            end

            % Estrai i nomi delle feature selezionate
            app.SelectedFeatures = cell(size(selectedCells, 1), 3);
            for i = 1:size(selectedCells, 1)
                row = selectedCells(i, 1);
                col = selectedCells(i, 2);
                app.SelectedFeatures{i, 1} = app.RowLabels{row};
                app.SelectedFeatures{i, 2} = app.ColLabels{col};
                app.SelectedFeatures{i, 3} = app.FischerScores(row, col);
            end

            % Mostra le feature selezionate nella console MATLAB
            disp('Selected features:');
            disp(app.SelectedFeatures); 
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
             if isempty(app.SelectedFeatures)
                % Avvisa l'utente se non ha selezionato nessuna feature
                uialert(app.UIFigure, 'Nessuna feature selezionata', 'Attenzione');
            else
                % Salva le feature selezionate in un file .mat
                [file, path] = uiputfile('selected_features.mat', 'Salva le feature selezionate');
                if ischar(file)
                    % Crea una variabile temporanea
                    selectedFeatures = app.SelectedFeatures; 
                    save(fullfile(path, file), 'selectedFeatures', '-mat');
                    uialert(app.UIFigure, 'Features salvate con successo', 'Successo');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'jet');
            app.UIFigure.Name = 'MATLAB App';
            app.FischerTable = uitable(app.UIFigure);
            app.FischerTable.ColumnName = '';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initializeApp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end